classdef OSNAP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        G_main                          matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        StatusPanel                     matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        TabGroup_main                   matlab.ui.container.TabGroup
        SetUpTab                        matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        AnalysisDirectoryPanel          matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        SelectdirectorytosaveanalysisresultsLabel  matlab.ui.control.Label
        SelectOSNAPanalysisdirectoryButton  matlab.ui.control.Button
        ExperimentInformationPanel      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PhenotypeField                  matlab.ui.control.EditField
        AddReplicateButton              matlab.ui.control.Button
        AddPhenotypeButton              matlab.ui.control.Button
        ReplicateField                  matlab.ui.control.EditField
        ReplicatesListBox               matlab.ui.control.ListBox
        PhenotypesListBox               matlab.ui.control.ListBox
        CreateSampleFilesTab            matlab.ui.container.Tab
        G_CreateOSNAPSampleFilesTab     matlab.ui.container.GridLayout
        CreateOSNAPSampleFilesPanel     matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        AddSampleFiles                  matlab.ui.control.Button
        CreateSampleFiles               matlab.ui.control.Button
        PixelsizenmpixEditField         matlab.ui.control.NumericEditField
        PixelsizenmpixEditFieldLabel    matlab.ui.control.Label
        SampleDataPanel_2               matlab.ui.container.Panel
        G_EditSampleDataPanel           matlab.ui.container.GridLayout
        ReplicateDropDown               matlab.ui.control.DropDown
        ReplicateDropDownLabel          matlab.ui.control.Label
        AssignReplicatestoSamplesPanel  matlab.ui.container.Panel
        G_SampleDataPanel               matlab.ui.container.GridLayout
        SampleDataTab                   matlab.ui.container.TabGroup
        AllSamplesTab                   matlab.ui.container.Tab
        G_AllSamplesTab                 matlab.ui.container.GridLayout
        AllListbox                      matlab.ui.control.ListBox
        UnassignedSamplesTab            matlab.ui.container.Tab
        G_UnassignedSamplesTab          matlab.ui.container.GridLayout
        UnassignedListbox               matlab.ui.control.ListBox
        AssignReplicateButton           matlab.ui.control.Button
        DeleteSelectedSamplesButton     matlab.ui.control.Button
        DeleteAllSamplesButton          matlab.ui.control.Button
        AddSamplesSelectlocalizationdatafilesButton  matlab.ui.control.Button
        AnalysisParametersTab           matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        AnalysisOptionsPanel            matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        RunAnalysisPanel                matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        RunOSNAPanalysisButton          matlab.ui.control.Button
        SelectdirectorytosaveanalysisresultsLabel_2  matlab.ui.control.Label
        RunStatusLabel                  matlab.ui.control.Label
        FeatureSelectionClassificationPanel  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        SplitmethodDropDown             matlab.ui.control.DropDown
        SplitmethodDropDownLabel        matlab.ui.control.Label
        RunclassificationonwholedataCheckBox  matlab.ui.control.CheckBox
        RunfeatureselectionCheckBox     matlab.ui.control.CheckBox
        ProportionEditField             matlab.ui.control.NumericEditField
        ProportionEditFieldLabel        matlab.ui.control.Label
        modelspertypeEditField          matlab.ui.control.NumericEditField
        modelspertypeEditFieldLabel     matlab.ui.control.Label
        MinexplainedEditField           matlab.ui.control.NumericEditField
        MinexplainedEditFieldLabel      matlab.ui.control.Label
        FeatureselectmaxEditField       matlab.ui.control.NumericEditField
        FeatureselectmaxEditFieldLabel  matlab.ui.control.Label
        foldsEditField                  matlab.ui.control.NumericEditField
        foldsEditFieldLabel             matlab.ui.control.Label
        RunclassificationbatchwiseCheckBox  matlab.ui.control.CheckBox
        RunPrincipalComponentAnalysisCheckBox  matlab.ui.control.CheckBox
        VolcanoAnalysisPanel            matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        FoldchangecutoffEditField       matlab.ui.control.NumericEditField
        FoldchangecutoffEditField_2Label  matlab.ui.control.Label
        pvaluecutoffEditField           matlab.ui.control.NumericEditField
        pvaluecutoffEditField_2Label    matlab.ui.control.Label
        RunvolcanoanalysisCheckBox      matlab.ui.control.CheckBox
        RunParametersPanel              matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ProcessesSpinner                matlab.ui.control.Spinner
        ProcessesSpinnerLabel           matlab.ui.control.Label
        SuffixEditField                 matlab.ui.control.EditField
        SuffixEditFieldLabel            matlab.ui.control.Label
        CheckoverwriteCheckBox          matlab.ui.control.CheckBox
        SaveiferrorCheckBox             matlab.ui.control.CheckBox
        SaveCheckBox                    matlab.ui.control.CheckBox
        FeatureSetEnrichmentAnalysisPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        UniversesDropDown               matlab.ui.control.DropDown
        UniversesDropDownLabel          matlab.ui.control.Label
        RankmetricDropDown              matlab.ui.control.DropDown
        RankmetricDropDownLabel         matlab.ui.control.Label
        RunfeatureenrichmentanalysisCheckBox  matlab.ui.control.CheckBox
        OtherPlotsPanel                 matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        RunplotviolinCheckBox           matlab.ui.control.CheckBox
        RunplotradialpersampleCheckBox  matlab.ui.control.CheckBox
        RunvenndiagramifpossibleCheckBox  matlab.ui.control.CheckBox
        SampleFeatureGenerationPanel    matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        CreatefeaturetableCheckBox      matlab.ui.control.CheckBox
        ExtractsamplefeaturesCheckBox   matlab.ui.control.CheckBox
        ContextMenu_P                   matlab.ui.container.ContextMenu
        EditselectedMenu_P              matlab.ui.container.Menu
        DeleteselectedMenu_P            matlab.ui.container.Menu
        DeleteallMenuP                  matlab.ui.container.Menu
        ContextMenu_R                   matlab.ui.container.ContextMenu
        EditselectedMenu_R              matlab.ui.container.Menu
        DeleteselectedMenu_R            matlab.ui.container.Menu
        DeleteallMenu_R                 matlab.ui.container.Menu
        ContextMenu_Sample              matlab.ui.container.ContextMenu
        AssignreplicateMenu             matlab.ui.container.Menu
        DeleteselectedMenu              matlab.ui.container.Menu
        DeleteallMenu                   matlab.ui.container.Menu
    end


    properties (Access = private, SetObservable)
        phenotypes string = strings(0) % Phenotype labels
        replicates string = strings(0) % Replicate labels
        pixel_size_nm double = 1; % Scale factor for nm per pixel
        samples table = cell2table(cell(0,4), 'VariableNames', {'Sample', 'Path', 'Replicate', 'Phenotype'});
        work_dir string = "";
        ready_flags logical = zeros(1,7);
        replicate_tabs cell = cell(0); % Cell array of tab handles
        context_menu_replicate cell = cell(0); % Cell array of context menu handles
        run_options struct = get_OSNAP_default_options();
    end

    methods (Access = private)

        function update_sample_data(app)
            % not enough reps
            if numel(app.replicates)==0
                app.AssignReplicateButton.Enable = "off";
                app.ReplicateDropDown.Enable = "off";
                app.AssignReplicateButton.Text = "Add replicates to begin assigning";
                % enough replicates present
            else
                app.ReplicateDropDown.Enable = "on";
                app.AssignReplicateButton.Enable = "on";
                app.ReplicateDropDown.Items = app.ReplicatesListBox.Items; % update dropdown
                app.AssignReplicateButton.Text = "Assign Selected Samples to "+app.ReplicateDropDown.Value; % update button text
                % update button name
            end
            % some files missing filepath
            if numel(app.samples.Path) == 0
                app.CreateSampleFiles.Enable = "off";
                app.CreateSampleFiles.Text = {'Create Sample Files'; '<i>Samples loaded without file paths</i>'};
                app.AddSampleFiles.Enable = "off";
                app.AddSampleFiles.Text = {'Add Sample Files'; '<i>Samples loaded without file paths</i>'};
                % prevent user from Extracting/Creating feature table, 
                % since sample files paths are not present
                app.run_options.run_extract_features = 0;
                app.ExtractsamplefeaturesCheckBox.Enable = "off";
                app.run_options.run_generate_table = 0;
                app.CreatefeaturetableCheckBox.Enable = "off";
            elseif any(app.samples.Path == "")
                app.CreateSampleFiles.Enable = "off";
                app.CreateSampleFiles.Text = {'Create Sample Files'; '<i>Samples loaded without file paths</i>'};
                app.AddSampleFiles.Enable = "off";
                app.AddSampleFiles.Text = {'Add Sample Files'; '<i>Samples loaded without file paths</i>'};
                % prevent user from Extracting/Creating feature table, 
                % since sample files paths are not present
                app.run_options.run_extract_features = 0;
                app.ExtractsamplefeaturesCheckBox.Enable = "off";
                app.run_options.run_generate_table = 0;
                app.CreatefeaturetableCheckBox.Enable = "off";
            else
                app.CreateSampleFiles.Enable = "on";
                app.CreateSampleFiles.Text = {'Create Sample Files'; '<i>Deletes all directory contents</i>'};
                app.AddSampleFiles.Enable = "on";
                app.AddSampleFiles.Text = {'Add Sample Files'; '<i>Overwrites existing data</i>'};
                % prevent user from Extracting/Creating feature table, 
                % since sample files paths are not present
                app.run_options.run_extract_features = 1;
                app.ExtractsamplefeaturesCheckBox.Enable = "on";
                app.run_options.run_generate_table = 1;
                app.CreatefeaturetableCheckBox.Enable = "on";
            end
            % update sample table
            run_flags_sample = [true,true,true,true,true,true];
            if numel(app.phenotypes) < 2
                run_flags_sample(1) = false; % not enough phenotypes
            end
            if numel(app.replicates) < 1 
                run_flags_sample(2) = false; % not enough replicates
            end
            if size(app.samples,1) < 1
                run_flags_sample(3) = false; % no samples
            end
            for i=1:size(app.samples,1)
                % update phenotypes
                phenotype_match_idx = arrayfun(@(x) contains(app.samples{i,"Sample"}, x, 'IgnoreCase', 1), app.phenotypes);
                n_phenotypes_present = sum(phenotype_match_idx);
                if n_phenotypes_present == 0 % fails to match any phenotype
                    app.samples{i,"Phenotype"} = "";
                    run_flags_sample(4) = false;
                elseif n_phenotypes_present == 1 % matches just 1 phenotype
                    app.samples{i,"Phenotype"} = app.phenotypes(phenotype_match_idx);
                else % matches multiple phenotypes
                    app.samples{i,"Phenotype"} = "";
                    run_flags_sample(5) = false;
                end
                % update replicates if a replicate has been removed
                if app.samples{i,"Replicate"}=="" % still missing
                    run_flags_sample(6) = false;
                elseif ~ismember(app.samples{i,"Replicate"},app.replicates) % no longer present
                    app.samples{i,"Replicate"} = "";
                    run_flags_sample(6) = false;
                end
            end
            app.ready_flags(2:7) = run_flags_sample;
            update_sample_tabs(app)
        end

        function update_sample_tabs(app)
            selected_tab_old = app.SampleDataTab.SelectedTab.Title;
            % update all sample tab
            if size(app.samples,1)==0
                app.AllListbox.Items = {};
            else
                app.AllListbox.Items = app.samples{:,"Sample"};
                % adjust color in all box so that unassigned are grey
                s = uistyle(BackgroundColor=[0.9,0.9,0.9],FontColor='k');
                addStyle(app.AllListbox,s,"item",1:numel(app.AllListbox.Items));
            end
            % update unassigned sample tab
            if size(app.samples,1)==0
                app.UnassignedListbox.Items = {};
            elseif sum(app.samples{:,"Replicate"}=="")==0
                app.UnassignedListbox.Items = {};
            else
                app.UnassignedListbox.Items = app.samples{app.samples{:,"Replicate"}=="","Sample"};
            end
            % remove old tabs
            for i=1:numel(app.replicate_tabs)
                delete(app.replicate_tabs{i})
            end
            if numel(app.replicates) > 0 && size(app.samples,1)>0
                % create new tabs
                n_replicates = numel(app.replicates);
                colors_replicates = lines(n_replicates);
                app.replicate_tabs = cell(1,n_replicates);
                for i = 1:n_replicates
                    % create tab
                    app.replicate_tabs{i} = uitab(app.SampleDataTab,'Title',sprintf("Rep%s",app.replicates(i)));
                    g = uigridlayout(app.replicate_tabs{i},'ColumnWidth',{'1x'},'RowHeight',{'1x'},'Padding',[0,0,0,0]);
                    % create listbox in tab
                    lb = uilistbox(g);
                    lb.Layout.Row = 1;
                    lb.Layout.Column = 1;
                    lb.Multiselect = 'on';
                    lb.FontColor = colors_replicates(i,:);
                    lb.BackgroundColor = 0.9+colors_replicates(i,:)*0.1;
                    if sum(app.samples{:,"Replicate"}==app.replicates(i)) > 0
                        lb.Items = app.samples{app.samples{:,"Replicate"}==app.replicates(i),"Sample"};
                    else
                        lb.Items = {};
                    end
                    % add context menu
                    lb.ContextMenu = app.ContextMenu_Sample;
                    % adjust color in all samples listbox to correspond to replicate
                    s = uistyle(BackgroundColor=0.9+colors_replicates(i,:)*0.1,...
                        FontColor=colors_replicates(i,:));
                    addStyle(app.AllListbox,s,"item",find(app.samples{:,"Replicate"}==app.replicates(i)));
                end
            end
            % update selected tab
            new_tab_idx = arrayfun(@(x) string(x.Title)==selected_tab_old, app.SampleDataTab.Children,'uni',1);
            if any(selected_tab_old)
                app.SampleDataTab.SelectedTab = app.SampleDataTab.Children(new_tab_idx);
            else
                app.SampleDataTab.SelectedTab = app.AllSamplesTab;
            end
        end

        function check_to_enable_analysis(app)
            if all(app.ready_flags)
                app.CreateSampleFiles.Enable = 'on';
                app.AddSampleFiles.Enable = 'on';
                app.RunOSNAPanalysisButton.Enable = 'on';
                app.StatusLabel.Text = "Ready to run!";
                app.StatusLabel.FontColor = [0    0.6000    0.2000];
                app.StatusLabel.FontSize = 14;
            else
                % create warnings
                warning_message = "";
                if ~app.ready_flags(1) % a valid work directory exists flag
                    warning_message = sprintf("%s\n%s",warning_message,"  - No valid analysis directory (1.1. Analysis Directory).");
                end
                if ~app.ready_flags(2) % enough phenotypes
                    warning_message = sprintf("%s\n%s",warning_message,"  - Not enough phenotypes (1.2. Experiment Information).");
                end
                if ~app.ready_flags(3) % enough replicates
                    warning_message = sprintf("%s\n%s",warning_message,"  - Not enough replicates (2. Experiment Information).");
                end
                if ~app.ready_flags(4) % samples exist
                    warning_message = sprintf("%s\n%s",warning_message,"  - No samples loaded (3. Sample Data).");
                end
                if ~app.ready_flags(5) && app.ready_flags(1) % all samples have phenotype flag
                    warning_message = sprintf("%s\n%s",warning_message,"  - Samples do not have valid phenotype (3. Sample Data).");
                end
                if ~app.ready_flags(6) && app.ready_flags(1) % all samples have only one phenotype flag
                    warning_message = sprintf("%s\n%s",warning_message,"  - Samples have ambiguous phenotypes (3. Sample Data).");
                end
                if ~app.ready_flags(7) && app.ready_flags(1) % all samples have a replicate flag
                    warning_message = sprintf("%s\n%s",warning_message,"  - Samples not assigned to replicates (3. Sample Data).");
                end
                if any(app.ready_flags)
                    warning_message = sprintf("WARNING:%s",warning_message);
                end
                app.CreateSampleFiles.Enable = 'off';
                app.AddSampleFiles.Enable = 'off';
                app.RunOSNAPanalysisButton.Enable = 'off';
                app.StatusLabel.Text = warning_message;
                app.StatusLabel.FontColor = [0.6000    0    0];
                app.StatusLabel.FontSize = 14;
            end
        end

        function add_samples(app)
            % get information from selected files
            [Sample,Path] = uigetfile({'*.txt;*.csv'},'Select Localization Data File(s)','MultiSelect','on');
            % add to existing sample data
            if ~isempty(Sample)
                samples_add = cell2table(cell(0,4), 'VariableNames', {'Sample', 'Path', 'Replicate', 'Phenotype'});
                % single file
                if (class(Sample) == "string") || (class(Sample) == "char")
                    samples_add = cell2table({Sample, Path, "", ""});
                    samples_add.Properties.VariableNames = ["Sample","Path","Replicate","Phenotype"];
                    % multiple files
                elseif class(Sample) == "cell"
                    Sample = string(Sample');
                    Path = repmat(string(Path),[numel(Sample),1]);
                    Replicate = repmat("",[numel(Sample),1]);
                    Phenotype = repmat("",[numel(Sample),1]);
                    samples_add = table(Sample, Path, Replicate, Phenotype);
                end
                app.samples = [app.samples; samples_add];
                update_sample_data(app)
            end
        end

        function delete_samples_all(app)
            current_sample_tab = app.SampleDataTab.SelectedTab;
            switch current_sample_tab.Title
                case "All Samples"
                    app.samples(:,:) = [];
                case "Unassigned Samples"
                    app.samples(app.samples{:,"Replicate"} == "",:) = [];
                otherwise
                    app.samples(app.samples{:,"Replicate"} == extractAfter(current_sample_tab.Title,"Rep"),:) = [];
            end
            update_sample_data(app);
        end

        function delete_samples_selected(app)
            current_sample_tab = app.SampleDataTab.SelectedTab;
            current_selection = current_sample_tab.Children.Children.ValueIndex;
            switch current_sample_tab.Title
                case "All Samples"
                    app.samples(current_selection,:) = [];
                case "Unassigned Samples"
                    % unassigned samples
                    idx = find(app.samples{:,"Replicate"} == "");
                    % delete unassigned samples
                    app.samples(idx(current_selection),:) = [];
                otherwise
                    replicate_selected = extractAfter(current_sample_tab.Title,"Rep");
                    % replicate-assigned samples
                    idx = find(app.samples{:,"Replicate"} == replicate_selected);
                    % delete unassigned samples
                    app.samples(idx(current_selection),:) = [];
            end
            update_sample_data(app);
        end

        function assign_replicates_from_listbox(app)
            if (numel(app.replicates)) > 0
                current_sample_tab = app.SampleDataTab.SelectedTab;
                current_selection = current_sample_tab.Children.Children.ValueIndex;
                replicate_assigned = repmat(string(app.ReplicateDropDown.Value),[numel(current_selection),1]);
                switch current_sample_tab.Title
                    case "All Samples"
                        app.samples{current_selection,"Replicate"} = replicate_assigned;
                    case "Unassigned Samples"
                        % unassigned samples
                        idx = find(app.samples{:,"Replicate"} == "");
                        % selected unassigned samples
                        app.samples{idx(current_selection),"Replicate"} = replicate_assigned;
                    otherwise
                        % replicate-assigned samples
                        idx = find(app.samples{:,"Replicate"} == extractAfter(current_sample_tab.Title,"Rep"));
                        % selected unassigned samples
                        app.samples{idx(current_selection),"Replicate"} = replicate_assigned;
                end
                update_sample_data(app)
            end
        end

        function assign_replicates_from_menu(app,event)
            if (numel(app.replicates)) > 0
                current_sample_tab = app.SampleDataTab.SelectedTab;
                current_selection = current_sample_tab.Children.Children.ValueIndex;
                replicate_assigned = repmat(string(event.Source.Text),[numel(current_selection),1]);
                switch current_sample_tab.Title
                    case "All Samples"
                        app.samples{current_selection,"Replicate"} = replicate_assigned;
                    case "Unassigned Samples"
                        % unassigned samples
                        idx = find(app.samples{:,"Replicate"} == "");
                        % selected unassigned samples
                        app.samples{idx(current_selection),"Replicate"} = replicate_assigned;
                    otherwise
                        % replicate-assigned samples
                        idx = find(app.samples{:,"Replicate"} == extractAfter(current_sample_tab.Title,"Rep"));
                        % selected unassigned samples
                        app.samples{idx(current_selection),"Replicate"} = replicate_assigned;
                end
                update_sample_data(app)
            end
        end

        % function update_run_options(app)
        %     % app.run_options
        % end

        %% TODO include loading from analysis file
        % SNAP_nucleus_file_list = get_valid_OSNAP_samples(work_dir,groups,replicates,vars_to_load)

        
        function load_run_options(app,options)
            app.run_options.run_extract_features = options.run_extract_features;
            app.run_options.run_generate_table = options.run_generate_table;
            app.run_options.run_comparison = options.run_comparison;
            app.run_options.run_generate_batches = options.run_generate_batches;
            app.run_options.run_feature_selection = options.run_feature_selection;
            app.run_options.run_PCA = options.run_PCA;
            app.run_options.run_classification_all = options.run_classification_all;
            app.run_options.run_classification_batch = options.run_classification_batch;
            app.run_options.run_venn = options.run_venn;
            app.run_options.run_plot_violin = options.run_plot_violin;
            app.run_options.run_plot_radial = options.run_plot_radial;
            app.run_options.run_FSEA = options.run_FSEA;
            app.run_options.split_method = options.split_method;
            app.run_options.proportion = options.proportion;
            app.run_options.test_train_k = options.test_train_k;
            % feature select options
            app.run_options.feature_select_max_idx = options.feature_select_max_idx;
            % PCA options
            app.run_options.num_components_explained = options.num_components_explained;
            % classification options
            app.run_options.n_models_per_type = options.n_models_per_type;
            % comparison options
            app.run_options.alpha = options.alpha;
            app.run_options.fold_change_threshold = options.fold_change_threshold;
            % FSEA options
            app.run_options.feature_universe_names = options.feature_universe_names;
            app.run_options.FSEA_rank_type = options.FSEA_rank_type;
            % other options
            app.run_options.n_processes = options.n_processes;
            app.run_options.suffix = options.suffix;
            app.run_options.save = options.save;
            app.run_options.save_if_error = options.save_if_error;
            app.run_options.check_overwrite = options.check_overwrite;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            addlistener(app,'phenotypes','PostSet',@(src,evnt)update_sample_data(app));
            addlistener(app,'replicates','PostSet',@(src,evnt)update_sample_data(app));
            addlistener(app,'ready_flags','PostSet',@(src,evnt)check_to_enable_analysis(app));
            app.ProcessesSpinner.Limits = [1,maxNumCompThreads];
            app.UIFigure.Name = "O-SNAP";
        end

        % Value changed function: PixelsizenmpixEditField
        function PixelsizenmpixEditFieldValueChanged(app, event)
            app.pixel_size_nm = app.PixelsizenmpixEditField.Value;
        end

        % Value changed function: ReplicatesListBox
        function ReplicatesListBoxValueChanged(app, event)
            app.replicates = string(app.ReplicatesListBox.Items);
        end

        % Value changed function: PhenotypesListBox
        function PhenotypesListBoxValueChanged(app, event)
            app.phenotypes = string(app.PhenotypesListBox.Items);
        end

        % Button pushed function: 
        % AddSamplesSelectlocalizationdatafilesButton
        function AddSamplesSelectlocalizationdatafilesButtonPushed(app, event)
            add_samples(app);
        end

        % Button pushed function: CreateSampleFiles
        function CreateSampleFilesPushed(app, event)
            selection = questdlg(sprintf('Create sample files with scale factor of %.0f nm/pix?\nWARNING: All existing contents of\n%s\nwill be DELETED',app.pixel_size_nm,app.work_dir),...
                'Proceed?','Yes','No','No');
            switch selection
                case 'Yes'
                    delete(gcf)
                    % create O-SNAP sample files
                    f = waitbar(0,'Creating O-SNAP sample files...');
                    try
                        % delete existing contents for fresh run
                        rmdir(app.work_dir,'s')
                        mkdir(app.work_dir)
                        % generate new data
                        runtime = generate_OSNAP_samples_batch(app.samples,app.work_dir,app.pixel_size_nm);
                        close(f)
                        msgbox(sprintf('O-SNAP sample files created\n(%.2f minutes)',runtime/60));
                    catch ME
                        close(f)
                        errordlg(sprintf("Error:\n%s",getReport(ME,'extended','hyperlinks','off')),'Error')
                    end
                case 'No'
                    return
            end
        end

        % Button pushed function: AddSampleFiles
        function AddSampleFilesPushed(app, event)
            selection = questdlg(sprintf('Add sample files with scale factor of %.0f nm/pix?\nWARNING: Existing data will be overwritten, but not deleted',app.pixel_size_nm),...
                'Proceed?','Yes','No','No');
            switch selection
                case 'Yes'
                    delete(gcf)
                    % create O-SNAP sample files
                    f = waitbar(0,'Creating O-SNAP sample files...');
                    try
                        % generate new data
                        runtime = generate_OSNAP_samples_batch(app.samples,app.work_dir,app.pixel_size_nm);
                        close(f)
                        msgbox(sprintf('O-SNAP sample files created\n(%.2f minutes)',runtime/60));
                    catch ME
                        close(f)
                        errordlg(sprintf("Error:\n%s",getReport(ME,'extended','hyperlinks','off')),'Error')
                    end
                case 'No'
                    return
            end
        end

        % Value changed function: ReplicateDropDown
        function ReplicateDropDownValueChanged(app, event)
            if numel(app.replicates) > 0
                value = app.ReplicateDropDown.Value;
                app.AssignReplicateButton.Text = "Assign Selected Samples to Rep"+value;
            end
        end

        % Button pushed function: AssignReplicateButton
        function AssignReplicateButtonPushed(app, event)
            assign_replicates_from_listbox(app)
        end

        % Button pushed function: DeleteSelectedSamplesButton
        function DeleteSelectedSamplesButtonPushed(app, event)
            delete_samples_selected(app);
        end

        % Button pushed function: SelectOSNAPanalysisdirectoryButton
        function SelectOSNAPanalysisdirectoryButtonPushed(app, event)
            selected_path = uigetdir(char(java.lang.System.getProperty('user.home')),'Select directory to save O-SNAP analysis run');
            app.CreatefeaturetableCheckBox.Enable = "on";
            app.ExtractsamplefeaturesCheckBox.Enable = "on";
            if selected_path == 0
                return
            elseif ~exist(selected_path,'dir')
                return
            end
            app.work_dir = selected_path;
            app.SelectdirectorytosaveanalysisresultsLabel.Text = app.work_dir;
            app.SelectdirectorytosaveanalysisresultsLabel_2.Text = app.work_dir;
            app.ready_flags(1) = true;
            [~,analysis_name,~] = fileparts(app.work_dir);
            analysis_file = fullfile(app.work_dir,analysis_name+".mat");
            try
                has_sample_files = 1;
                f = waitbar(0,'Checking directory...');
                OSNAP_sample_file_list = get_all_valid_OSNAP_samples(app.work_dir,{'x','y'});
                close(f)
            catch ME
                close(f)
                if ME.identifier == "OSNAP:no_valid_files_found"
                    has_sample_files = 0;
                else
                    errordlg(sprintf('Error: Could not load sample data from %s\n%s',app.work_dir,getReport(ME,'extended','hyperlinks','off')),'Error')
                end
            end
            if exist(analysis_file,'file')
                % sample data is also present
                if has_sample_files
                    % allow Extracting/Creating feature table, since sample 
                    %  files are present in the directory
                    app.run_options.run_extract_features = 1;
                    app.ExtractsamplefeaturesCheckBox.Enable = "on";
                    app.run_options.run_generate_table = 1;
                    app.CreatefeaturetableCheckBox.Enable = "on";
                    selection = questdlg(sprintf('Directory already has samples and analysis data.\nLoad experiment information?'),...
                        'Load from directory','Yes','No','Yes, without phenotypes','Yes');
                    % load the analysis file and sample information
                    if selection == "Yes"
                        try
                            f = waitbar(0,"Loading analysis file...");
                            loaded_data = load_variables_OSNAP(analysis_file,{'groups','replicates','options'});
                            close(f);
                            T = renamevars(OSNAP_sample_file_list(:,["name","replicate","filepath"]),...
                                ["name","replicate","filepath"],["Sample","Replicate","Path"]);
                            T.Phenotype = repmat("",size(T,1),1);
                            app.samples = T(:,["Sample","Path","Phenotype","Replicate"]);
                            idx_loaded_w_ext = endsWith(app.samples.Path,".mat");
                            if any(idx_loaded_w_ext)
                                [a,b,c] = fileparts(app.samples.Path);
                                app.samples.Sample = fullfile(b+"."+c);
                                app.samples.Path = fullfile(a);
                            end
                            app.ReplicatesListBox.Items = string(loaded_data.replicates);
                            app.replicates = string(loaded_data.replicates);
                            app.PhenotypesListBox.Items = string(loaded_data.groups);
                            app.phenotypes = string(loaded_data.groups);
                            load_run_options(app,loaded_data.options)
                            clearvars T
                        catch ME
                            errordlg(sprintf('Error: Could not load analysis from %s\n%s',analysis_file,getReport(ME,'extended','hyperlinks','off')),'Error')
                        end
                    % load only the sample information
                    elseif selection == "Yes, without phenotypes"
                        try
                            T = renamevars(OSNAP_sample_file_list(:,["name","replicate","filepath"]),...
                                ["name","replicate","filepath"],["Sample","Replicate","Path"]);
                            T.Phenotype = repmat("",size(T,1),1);
                            app.samples = T(:,["Sample","Path","Phenotype","Replicate"]);
                            idx_loaded_w_ext = endsWith(app.samples.Path,".mat");
                            if any(idx_loaded_w_ext)
                                [a,b,c] = fileparts(app.samples.Path);
                                app.samples.Sample = fullfile(b+"."+c);
                                app.samples.Path = fullfile(a);
                            end
                            app.ReplicatesListBox.Items = string(unique(T.Replicate)');
                            app.replicates = string(unique(T.Replicate)');
                            clearvars T
                        catch ME
                            errordlg(sprintf('Error: Could not load sample data from %s\n%s',app.work_dir,getReport(ME,'extended','hyperlinks','off')),'Error')
                        end
                    end
                % only analysis data present
                else
                    % prevent user from Extracting/Creating feature table, 
                    % since sample files are not present in the directory
                    app.run_options.run_extract_features = 0;
                    app.ExtractsamplefeaturesCheckBox.Enable = "off";
                    app.run_options.run_generate_table = 0;
                    app.CreatefeaturetableCheckBox.Enable = "off";
                    selection = questdlg(sprintf('Directory already has analysis data.\nLoad experiment information?'),...
                    'Load from directory','Yes','No','Yes');
                    if selection == "Yes"
                        try
                            f = waitbar(0,"Loading analysis file...");
                            loaded_data = load_variables_OSNAP(analysis_file,{'groups','replicates','options','feature_data'});
                            close(f);
                            T = renamevars(loaded_data.feature_data(:,["group","biological_replicate","name"]),["group","biological_replicate","name"], ...
                                ["Phenotype","Replicate","Sample"]);
                            T.Path = repmat("",size(T,1),1);
                            app.samples = T(:,["Sample","Path","Phenotype","Replicate"]);
                            app.ReplicatesListBox.Items = string(unique(T.Replicate)');
                            app.replicates = string(unique(T.Replicate)');
                            app.PhenotypesListBox.Items = string(loaded_data.groups);
                            app.phenotypes = string(loaded_data.groups);
                            load_run_options(app,loaded_data.options)
                            clearvars T
                        catch ME
                            errordlg(sprintf('Error: Could not load analysis from %s\n%s',analysis_file,getReport(ME,'extended','hyperlinks','off')),'Error')
                        end
                    end
                end
            % analysis file is not present but sample data is
            elseif has_sample_files
                % allow Extracting/Creating feature table, since sample 
                %  files are present in the directory
                app.run_options.run_extract_features = 1;
                app.ExtractsamplefeaturesCheckBox.Enable = "on";
                app.run_options.run_generate_table = 1;
                app.CreatefeaturetableCheckBox.Enable = "on";
                selection = questdlg(sprintf('Directory already has sample data.\nLoad experiment information?\n(Phenotypes must still be provided)'),...
                'Load from directory','Yes','No','Yes');
                if selection == "Yes"
                    try
                        T = renamevars(OSNAP_sample_file_list(:,["name","replicate","filepath"]),...
                                ["name","replicate","filepath"],["Sample","Replicate","Path"]);
                        T = T(:,["Sample","Replicate","Path"]);
                        T.Phenotype = repmat("",size(T,1),1);
                        app.samples = T(:,["Sample","Path","Phenotype","Replicate"]);
                        app.ReplicatesListBox.Items = string(unique(T.Replicate)');
                        app.replicates = string(unique(T.Replicate)');
                        clearvars T
                    catch ME
                        errordlg(sprintf('Error: Could not sample data from %s\n%s',app.work_dir,getReport(ME,'extended','hyperlinks','off')),'Error')
                    end
                end
            else
                warndlg(sprintf('Warning: No analysis or sample data found in\n%s\nAdd sample data in Section 2',analysis_file),'Warning')
            end
        end

        % Button pushed function: AddPhenotypeButton
        function AddPhenotypeButtonPushed(app, event)
            phenotype_to_add = app.PhenotypeField.Value;
            if phenotype_to_add ~= ""
                if numel(app.phenotypes) == 0
                    app.PhenotypesListBox.Items = string(phenotype_to_add);
                elseif ~ismember(phenotype_to_add,app.PhenotypesListBox.Items) % if is new phenotype
                    app.PhenotypesListBox.Items = [app.PhenotypesListBox.Items phenotype_to_add];
                end
                app.phenotypes = string(app.PhenotypesListBox.Items);
                app.PhenotypeField.Value = "";
            end
        end

        % Button pushed function: AddReplicateButton
        function AddReplicateButtonPushed(app, event)
            replicate_to_add = app.ReplicateField.Value;
            if replicate_to_add ~= ""
                if numel(app.replicates) == 0
                    app.ReplicatesListBox.Items = string(replicate_to_add);
                elseif ~ismember(replicate_to_add,app.ReplicatesListBox.Items) % if is new replicate
                    app.ReplicatesListBox.Items = [app.ReplicatesListBox.Items replicate_to_add];
                end
                app.replicates = string(app.ReplicatesListBox.Items);
                app.ReplicateField.Value = "";
            end
        end

        % Menu selected function: EditselectedMenu_P
        function EditselectedMenu_PSelected(app, event)
            input = inputdlg(...
                sprintf("Change name of %s",app.PhenotypesListBox.Value),...
                "Change phenotype label",...
                [1 50],"");
            if ~isempty(input)
                app.PhenotypesListBox.Items(app.PhenotypesListBox.ValueIndex) = input;
                app.phenotypes = string(app.PhenotypesListBox.Items);
            end
        end

        % Menu selected function: EditselectedMenu_R
        function EditselectedMenu_RSelected(app, event)
            input = inputdlg(...
                sprintf("Change name of %s",app.ReplicatesListBox.Value),...
                "Change replicate label",...
                [1 50],"");
            if ~isempty(input)
                app.ReplicatesListBox.Items(app.ReplicatesListBox.ValueIndex) = input;
                app.replicates = string(app.ReplicatesListBox.Items);
            end
        end

        % Menu selected function: DeleteselectedMenu_P
        function DeleteselectedMenu_PSelected(app, event)
            samples_w_phenotype_idx = app.samples{:,"Phenotype"}==string(app.PhenotypesListBox.Value);
            if any(samples_w_phenotype_idx)
                selection = questdlg(sprintf('Some samples have selected phenotypes. Delete these samples as well?'),...
                'Load from directory','Yes','No','Cancel','Yes');
                if selection == "Yes"
                    app.samples(samples_w_phenotype_idx,:) = [];
                    app.PhenotypesListBox.Items(app.PhenotypesListBox.ValueIndex)=[];
                    app.phenotypes = string(app.PhenotypesListBox.Items);
                elseif selection == "No"
                    app.PhenotypesListBox.Items(app.PhenotypesListBox.ValueIndex)=[];
                    app.phenotypes = string(app.PhenotypesListBox.Items);
                    warndlg('Warning: Some samples may no have valid phenotype','warning')
                end
            end
        end

        % Menu selected function: DeleteselectedMenu_R
        function DeleteselectedMenu_RSelected(app, event)
            app.ReplicatesListBox.Items(app.ReplicatesListBox.ValueIndex)=[];
            app.replicates = string(app.ReplicatesListBox.Items);
            samples_w_replicate_idx = app.samples{:,"Replicate"}==string(app.ReplicatesListBox.Value);
            if any(samples_w_replicate_idx)
                selection = questdlg(sprintf('Some samples have selected replicates. Delete these samples as well?'),...
                'Load from directory','Yes','No','Cancel','Yes');
                if selection == "Yes"
                    app.samples(samples_w_replicate_idx,:) = [];
                    app.ReplicatesListBox.Items(app.ReplicatesListBox.ValueIndex)=[];
                    app.replicates = string(app.ReplicatesListBox.Items);
                elseif selection == "No"
                    app.ReplicatesListBox.Items(app.ReplicatesListBox.ValueIndex)=[];
                    app.replicates = string(app.ReplicatesListBox.Items);
                    warndlg('Warning: Some samples may no have valid replicate','warning')
                end
            end
        end

        % Menu selected function: DeleteallMenuP
        function DeleteallMenuPSelected(app, event)
            app.PhenotypesListBox.Items = {};
            app.phenotypes = string([]);
        end

        % Menu selected function: DeleteallMenu_R
        function DeleteallMenu_RSelected(app, event)
            app.ReplicatesListBox.Items = {};
            app.replicates = string([]);
        end

        % Clicked callback: ReplicatesListBox
        function ReplicatesListBoxClicked(app, event)
            if (numel(app.replicates) > 0) && ~isempty(event.InteractionInformation.Item) && (app.ReplicateDropDown.Enable == "on")
                item = event.InteractionInformation.Item;
                app.ReplicateDropDown.Value = app.ReplicateDropDown.Items(item);
                app.AssignReplicateButton.Text = "Assign Selected Samples to Rep"+string(app.replicates(item));
            end
        end

        % Menu selected function: DeleteselectedMenu
        function DeleteselectedMenuSelected(app, event)
            delete_samples_selected(app)
        end

        % Menu selected function: DeleteallMenu
        function DeleteallMenuSelected(app, event)
            delete_samples_all(app)
        end

        % Context menu opening function: ContextMenu_Sample
        function ContextMenu_SampleOpening(app, event)
            current_sample_tab = app.SampleDataTab.SelectedTab;
            n_selected = numel(current_sample_tab.Children.Children.ValueIndex);
            for i=1:numel(app.context_menu_replicate)
                delete(app.context_menu_replicate{i});
            end
            if (numel(app.replicates) > 0) && (n_selected > 0)
                app.context_menu_replicate = cell(1,numel(app.replicates));
                for i=1:numel(app.replicates)
                    app.context_menu_replicate{i} = uimenu(app.AssignreplicateMenu);
                    app.context_menu_replicate{i}.Text = app.replicates(i);
                    app.context_menu_replicate{i}.MenuSelectedFcn =  createCallbackFcn(app, @assign_replicates_from_menu, true);
                end
            elseif (numel(app.replicates) > 0) && (n_selected == 0)
                app.context_menu_replicate = cell(1,1);
                app.context_menu_replicate{1} = uimenu(app.AssignreplicateMenu);
                app.context_menu_replicate{1}.Text = "Need samples to assign";
                app.context_menu_replicate{1}.Enable = "off";
            elseif (numel(app.replicates) == 0) && (n_selected > 0)
                app.context_menu_replicate{1} = cell(1,1);
                app.context_menu_replicate{1} = uimenu(app.AssignreplicateMenu);
                app.context_menu_replicate{1}.Text = "Add replicates to assign";
                app.context_menu_replicate{1}.Enable = "off";
            end
        end

        % Selection change function: SampleDataTab
        function SampleDataTabSelectionChanged(app, event)
            selected_tab_name = app.SampleDataTab.SelectedTab.Title;
            switch selected_tab_name
                case "All Samples"
                    app.DeleteAllSamplesButton.Text = "Delete All Samples";
                case "Unassigned Samples"
                    app.DeleteAllSamplesButton.Text = "Delete All Unassigned Samples";
                otherwise
                    app.DeleteAllSamplesButton.Text = sprintf("Delete All %s", selected_tab_name);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('Close the Software?','Exit Callback','Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end
        end

        % Value changed function: SaveCheckBox
        function SaveCheckBoxValueChanged(app, event)
            app.run_options.save = app.SaveCheckBox.Value;
            if app.run_options.save
                app.SaveiferrorCheckBox.Enable = "on";
                app.CheckoverwriteCheckBox.Enable = "on";
                app.SuffixEditField.Enable = "on";
            else
                app.SaveiferrorCheckBox.Value = false;
                app.SaveiferrorCheckBox.Enable = "off";
                app.run_options.check_overwrite = false;
                app.CheckoverwriteCheckBox.Value = false;
                app.CheckoverwriteCheckBox.Enable = "off";
                app.run_options.suffix = "";
                app.SuffixEditField.Value = "";
                app.SuffixEditField.Enable = "off";
            end
        end

        % Value changed function: SaveiferrorCheckBox
        function SaveiferrorCheckBoxValueChanged(app, event)
            app.run_options.save_if_error = app.SaveiferrorCheckBox.Value;
        end

        % Value changed function: CheckoverwriteCheckBox
        function CheckoverwriteCheckBoxValueChanged(app, event)
            app.run_options.check_overwrite = app.CheckoverwriteCheckBox.Value;
        end

        % Value changed function: ProcessesSpinner
        function ProcessesSpinnerValueChanged(app, event)
            app.run_options.n_processes = app.ProcessesSpinner.Value;
        end

        % Value changed function: SuffixEditField
        function SuffixEditFieldValueChanged(app, event)
            app.run_options.suffix = app.SuffixEditField.Value;
        end

        % Value changed function: ExtractsamplefeaturesCheckBox
        function ExtractsamplefeaturesCheckBoxValueChanged(app, event)
            app.run_options.run_extract_features = app.ExtractsamplefeaturesCheckBox.Value;
        end

        % Value changed function: CreatefeaturetableCheckBox
        function CreatefeaturetableCheckBoxValueChanged(app, event)
            app.run_options.run_generate_table = app.CreatefeaturetableCheckBox.Value;
        end

        % Value changed function: RunvolcanoanalysisCheckBox
        function RunvolcanoanalysisCheckBoxValueChanged(app, event)
            app.run_options.run_comparison = app.RunvolcanoanalysisCheckBox.Value;
            if app.run_options.run_comparison
                app.pvaluecutoffEditField.Enable = "on";
                app.FoldchangecutoffEditField.Enable = "on";
            else
                app.pvaluecutoffEditField.Enable = "off";
                app.FoldchangecutoffEditField.Enable = "off";
            end
        end

        % Value changed function: pvaluecutoffEditField
        function pvaluecutoffEditFieldValueChanged(app, event)
            app.run_options.alpha = app.pvaluecutoffEditField.Value;
        end

        % Value changed function: FoldchangecutoffEditField
        function FoldchangecutoffEditFieldValueChanged(app, event)
            app.run_options.fold_change_threshold  = app.FoldchangecutoffEditField.Value;
        end

        % Value changed function: SplitmethodDropDown
        function SplitmethodDropDownValueChanged(app, event)
            app.run_options.split_method = app.SplitmethodDropDown.Value;
            switch app.run_options.split_method
                case "k-fold"
                    app.ProportionEditField.Enable = "off";
                    app.foldsEditField.Enable = "on";
                case "replicate"
                    app.ProportionEditField.Enable = "off";
                    app.foldsEditField.Enable = "off";
                case "bootstrap"
                    app.ProportionEditField.Enable = "on";
                    app.foldsEditField.Enable = "on";
                case "none"
                    app.ProportionEditField.Enable = "off";
                    app.foldsEditField.Enable = "off";
            end
        end

        % Value changed function: ProportionEditField
        function ProportionEditFieldValueChanged(app, event)
            app.run_options.proportion = app.ProportionEditField.Value;
        end

        % Value changed function: foldsEditField
        function foldsEditFieldValueChanged(app, event)
            app.run_options.test_train_k = app.foldsEditField.Value;
        end

        % Value changed function: RunfeatureselectionCheckBox
        function RunfeatureselectionCheckBoxValueChanged(app, event)
            app.run_options.run_feature_selection = app.RunfeatureselectionCheckBox.Value;
            if app.run_options.run_feature_selection
                app.FeatureselectmaxEditField.Enable = "on";
            else
                app.FeatureselectmaxEditField.Enable = "off";
            end
            if any([app.run_options.run_feature_selection,...
                    app.RunPrincipalComponentAnalysisCheckBox.Value,...
                    app.run_options.run_classification_batch,...
                    app.run_options.run_classification_all])
                app.SplitmethodDropDown.Enable = 'on';
                app.ProportionEditField.Enable = 'on';
                app.foldsEditField.Enable = 'on';
            else
                app.SplitmethodDropDown.Enable = 'off';
                app.ProportionEditField.Enable = 'off';
                app.foldsEditField.Enable = 'off';
            end
        end

        % Value changed function: FeatureselectmaxEditField
        function FeatureselectmaxEditFieldValueChanged(app, event)
            app.run_options.feature_select_max_idx = app.FeatureselectmaxEditField.Value;
        end

        % Value changed function: RunPrincipalComponentAnalysisCheckBox
        function RunPrincipalComponentAnalysisCheckBoxValueChanged(app, event)
            app.run_options.run_PCA = app.RunPrincipalComponentAnalysisCheckBox.Value;
            if app.run_options.run_PCA
                app.MinexplainedEditField.Enable = "on";
            else
                app.MinexplainedEditField.Enable = "off";
            end
        end

        % Value changed function: MinexplainedEditField
        function MinexplainedEditFieldValueChanged(app, event)
            app.run_options.num_components_explained = app.MinexplainedEditField.Value;
        end

        % Value changed function: RunclassificationbatchwiseCheckBox
        function RunclassificationbatchwiseCheckBoxValueChanged(app, event)
            app.run_options.run_classification_batch = app.RunclassificationbatchwiseCheckBox.Value;
            if app.run_options.run_classification_batch || app.run_options.run_classification_all
                app.modelspertypeEditField.Enable = "on";
            else
                app.modelspertypeEditField.Enable = "off";
            end
            if any([app.run_options.run_feature_selection,...
                    app.RunPrincipalComponentAnalysisCheckBox.Value,...
                    app.run_options.run_classification_batch,...
                    app.run_options.run_classification_all])
                app.SplitmethodDropDown.Enable = 'on';
                app.ProportionEditField.Enable = 'on';
                app.foldsEditField.Enable = 'on';
            else
                app.SplitmethodDropDown.Enable = 'off';
                app.ProportionEditField.Enable = 'off';
                app.foldsEditField.Enable = 'off';
            end
        end

        % Value changed function: modelspertypeEditField
        function modelspertypeEditFieldValueChanged(app, event)
            app.run_options.n_models_per_type = app.modelspertypeEditField.Value;
        end

        % Value changed function: RunclassificationonwholedataCheckBox
        function RunclassificationonwholedataCheckBoxValueChanged(app, event)
            app.run_options.run_classification_all = app.RunclassificationonwholedataCheckBox.Value;
            if app.run_options.run_classification_batch || app.run_options.run_classification_all
                app.modelspertypeEditField.Enable = "on";
            else
                app.modelspertypeEditField.Enable = "off";
            end
            if any([app.run_options.run_feature_selection,...
                    app.RunPrincipalComponentAnalysisCheckBox.Value,...
                    app.run_options.run_classification_batch,...
                    app.run_options.run_classification_all])
                app.SplitmethodDropDown.Enable = 'on';
                app.ProportionEditField.Enable = 'on';
                app.foldsEditField.Enable = 'on';
            else
                app.SplitmethodDropDown.Enable = 'off';
                app.ProportionEditField.Enable = 'off';
                app.foldsEditField.Enable = 'off';
            end
        end

        % Value changed function: RunfeatureenrichmentanalysisCheckBox
        function RunfeatureenrichmentanalysisCheckBoxValueChanged(app, event)
            app.run_options.run_FSEA = app.RunfeatureenrichmentanalysisCheckBox.Value;
            if app.run_options.run_FSEA
                app.UniversesDropDown.Enable = 'on';
                app.RankmetricDropDown.Enable = 'on';
            else
                app.UniversesDropDown.Enable = 'off';
                app.RankmetricDropDown.Enable = 'off';
            end
        end

        % Value changed function: UniversesDropDown
        function UniversesDropDownValueChanged(app, event)
            app.run_options.feature_universe_names = app.UniversesDropDown.Value;            
        end

        % Value changed function: RankmetricDropDown
        function RankmetricDropDownValueChanged(app, event)
            app.run_options.FSEA_rank_type = app.RankmetricDropDown.Value;            
        end

        % Button pushed function: RunOSNAPanalysisButton
        function RunOSNAPanalysisButtonPushed(app, event)
            % if ~isempty(app.work_dir)
                if exist(app.work_dir,"dir")
                    % try
                    %     OSNAP_sample_file_list = get_valid_OSNAP_samples(app.work_dir,cellstr(app.phenotypes),cellstr(app.replicates),{'x','y','name'});
                    %     phenotypes_loaded = unique(OSNAP_sample_file_list.group);
                    %     replicates_loaded = unique(OSNAP_sample_file_list.replicate);
                    % catch ME
                    %     if ME.identifier == "OSNAP:unmatched_phenotype"
                    %         warndlg("Warning: Inconsistent phenotype and replicates\nPlease confirm in Set Up tab","Warning");
                    %         return;
                    %     elseif ME.identifier == "OSNAP:no_valid_files_found"
                    %         phenotypes_loaded = 
                    %     end 
                    % end
                    % 
                    % if isequal(sort(phenotypes_loaded)', sort(app.phenotypes)) && isequal(sort(replicates_loaded)',sort(app.replicates))
                        try
                            [root_dir, analysis_name, ext] = fileparts(app.work_dir);
                            % ensure presence of periods in filename do not cause issues
                            analysis_name = strcat(analysis_name,ext);
                            % if suffix is already present in analysis name,
                            % remove it (can occur when loading from a previous
                            % run file)
                            if endsWith(analysis_name,"_"+app.run_options.suffix)
                                analysis_name = extractBefore(analysis_name,"_"+app.run_options.suffix);
                            end
                            % Change button name to "Processing"
                            app.RunOSNAPanalysisButton.Text = 'Running...';
                            app.RunOSNAPanalysisButton.Enable = 'off';
                            app.RunStatusLabel.Text = sprintf("Sit tight!\n");
                            setup_OSNAP;
                            % % Put text on top of icon
                            % app.ProcessDataButton.IconAlignment = 'bottom';
                            % % Create waitbar with same color as button
                            % wbar = permute(repmat(app.ProcessDataButton.BackgroundColor,15,1,200),[1,3,2]);
                            % % Black frame around waitbar
                            % wbar([1,end],:,:) = 0;
                            % wbar(:,[1,end],:) = 0;
                            % % Load the empty waitbar to the button
                            % app.ProcessDataButton.Icon = wbar;
                            % % Loop through something and update waitbar
                            % n = 10;
                            OSNAP_data = run_OSNAP_pipeline(root_dir,analysis_name,...
                                cellstr(app.phenotypes), ...
                                cellstr(app.replicates),...
                                "run_extract_features", app.run_options.run_extract_features,...
                                "run_generate_table", app.run_options.run_generate_table,...
                                "run_comparison", app.run_options.run_comparison,...
                                "run_generate_batches", app.run_options.run_generate_batches,...
                                "run_feature_selection", app.run_options.run_feature_selection,...
                                "run_PCA", app.run_options.run_PCA,...
                                "run_classification_all", app.run_options.run_classification_all,...
                                "run_classification_batch", app.run_options.run_classification_batch,...
                                "run_venn", app.run_options.run_venn,...
                                "run_plot_violin", app.run_options.run_plot_violin,...
                                "run_plot_radial", app.run_options.run_plot_radial,...
                                "run_FSEA", app.run_options.run_FSEA,...
                                ... generate batch options
                                "split_method", app.run_options.split_method,...
                                "proportion", app.run_options.proportion,...
                                "test_train_k", app.run_options.test_train_k,...
                                ... feature select options
                                "feature_select_max_idx", app.run_options.feature_select_max_idx,...
                                ... PCA options
                                "num_components_explained", app.run_options.num_components_explained,...
                                ... classification options
                                "n_models_per_type", app.run_options.n_models_per_type,...
                                ... comparison options
                                "alpha", app.run_options.alpha,...
                                "fold_change_threshold", app.run_options.fold_change_threshold,...
                                ... FSEA options
                                "feature_universe_names", app.run_options.feature_universe_names,...
                                "FSEA_rank_type", app.run_options.FSEA_rank_type,...
                                ... other options
                                "n_processes", app.run_options.n_processes,...
                                "suffix", app.run_options.suffix,...
                                "save", app.run_options.save,...
                                "save_if_error", app.run_options.save_if_error,...
                                "check_overwrite", app.run_options.check_overwrite);
                            app.RunOSNAPanalysisButton.Text = 'Run O-SNAP analysis...';
                            app.RunOSNAPanalysisButton.Enable = 'on';
                            [~,logfile,~] = fileparts(OSNAP_data.log_file);
                            app.RunStatusLabel.Text = sprintf("See output at\n%s",logfile);
                        catch ME
                            errordlg(sprintf('Error: Could not run analysis: %s\n%s',analysis_name,getReport(ME,'extended','hyperlinks','off')),'Error')
                            app.RunOSNAPanalysisButton.Text = 'Run O-SNAP analysis...';
                            app.RunOSNAPanalysisButton.Enable = 'on';
                            app.RunStatusLabel.Text = sprintf("Error: %s",getReport(ME,'extended','hyperlinks','off'));
                        end
                    end
                % end
            % end

        end

        % Size changed function: AnalysisDirectoryPanel
        function AnalysisDirectoryPanelSizeChanged(app, event)
            position = app.AnalysisDirectoryPanel.Position;
            
        end

        % Value changed function: RunplotviolinCheckBox
        function RunplotviolinCheckBoxValueChanged(app, event)
            app.run_options.run_violin = app.RunplotviolinCheckBox.Value;
        end

        % Value changed function: RunplotradialpersampleCheckBox
        function RunplotradialpersampleCheckBoxValueChanged(app, event)
            app.run_options.run_plot_radial = app.RunplotradialpersampleCheckBox.Value;
        end

        % Value changed function: RunvenndiagramifpossibleCheckBox
        function RunvenndiagramifpossibleCheckBoxValueChanged(app, event)
            app.run_options.run_venn = app.RunvenndiagramifpossibleCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.G_main = uigridlayout(app.UIFigure);
            app.TabGroup_main = uitabgroup(app.G_main);
            app.SetUpTab = uitab(app.TabGroup_main);
            app.SetUpTab.Title = 'Set Up';
            app.GridLayout18 = uigridlayout(app.SetUpTab);
            app.ExperimentInformationPanel = uipanel(app.GridLayout18);
            app.ExperimentInformationPanel.Title = '1.2. Experiment Information';
            app.GridLayout2 = uigridlayout(app.ExperimentInformationPanel);
            app.PhenotypesListBox = uilistbox(app.GridLayout2);
            app.PhenotypesListBox.Items = {''};
            app.PhenotypesListBox.ValueChangedFcn = createCallbackFcn(app, @PhenotypesListBoxValueChanged, true);
            app.PhenotypesListBox.Value = '';
            app.ReplicatesListBox = uilistbox(app.GridLayout2);
            app.ReplicatesListBox.Items = {''};
            app.ReplicatesListBox.ValueChangedFcn = createCallbackFcn(app, @ReplicatesListBoxValueChanged, true);
            app.ReplicatesListBox.ClickedFcn = createCallbackFcn(app, @ReplicatesListBoxClicked, true);
            app.ReplicatesListBox.Value = '';
            app.ReplicateField = uieditfield(app.GridLayout2, 'text');
            app.AddPhenotypeButton = uibutton(app.GridLayout2, 'push');
            app.AddPhenotypeButton.ButtonPushedFcn = createCallbackFcn(app, @AddPhenotypeButtonPushed, true);
            app.AddPhenotypeButton.Text = 'Add Phenotype';
            app.AddReplicateButton = uibutton(app.GridLayout2, 'push');
            app.AddReplicateButton.ButtonPushedFcn = createCallbackFcn(app, @AddReplicateButtonPushed, true);
            app.AddReplicateButton.Text = 'Add Replicate';
            app.PhenotypeField = uieditfield(app.GridLayout2, 'text');
            app.AnalysisDirectoryPanel = uipanel(app.GridLayout18);
            app.AnalysisDirectoryPanel.Title = '1.1. Analysis Directory';
            app.AnalysisDirectoryPanel.SizeChangedFcn = createCallbackFcn(app, @AnalysisDirectoryPanelSizeChanged, true);
            app.GridLayout20 = uigridlayout(app.AnalysisDirectoryPanel);
            app.SelectOSNAPanalysisdirectoryButton = uibutton(app.GridLayout20, 'push');
            app.SelectOSNAPanalysisdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOSNAPanalysisdirectoryButtonPushed, true);
            app.SelectOSNAPanalysisdirectoryButton.Text = 'Select O-SNAP analysis directory...';
            app.SelectdirectorytosaveanalysisresultsLabel = uilabel(app.GridLayout20);
            app.SelectdirectorytosaveanalysisresultsLabel.Text = '<<< Select directory to save analysis results';
            app.CreateSampleFilesTab = uitab(app.TabGroup_main);
            app.CreateSampleFilesTab.Title = 'Create Sample Files';
            app.G_CreateOSNAPSampleFilesTab = uigridlayout(app.CreateSampleFilesTab);
            app.SampleDataPanel_2 = uipanel(app.G_CreateOSNAPSampleFilesTab);
            app.SampleDataPanel_2.Title = '2.1. Sample Data';
            app.G_EditSampleDataPanel = uigridlayout(app.SampleDataPanel_2);
            app.AddSamplesSelectlocalizationdatafilesButton = uibutton(app.G_EditSampleDataPanel, 'push');
            app.AddSamplesSelectlocalizationdatafilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSamplesSelectlocalizationdatafilesButtonPushed, true);
            app.AddSamplesSelectlocalizationdatafilesButton.Text = {'Add Samples'; '(Select localization data files)'};
            app.DeleteAllSamplesButton = uibutton(app.G_EditSampleDataPanel, 'push');
            app.DeleteAllSamplesButton.Text = 'Delete All Samples';
            app.DeleteSelectedSamplesButton = uibutton(app.G_EditSampleDataPanel, 'push');
            app.DeleteSelectedSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedSamplesButtonPushed, true);
            app.DeleteSelectedSamplesButton.Text = 'Delete Selected Samples';
            app.AssignReplicateButton = uibutton(app.G_EditSampleDataPanel, 'push');
            app.AssignReplicateButton.ButtonPushedFcn = createCallbackFcn(app, @AssignReplicateButtonPushed, true);
            app.AssignReplicateButton.Enable = 'off';
            app.AssignReplicateButton.Text = 'Add replicates to begin assigning';
            app.AssignReplicatestoSamplesPanel = uipanel(app.G_EditSampleDataPanel);
            app.AssignReplicatestoSamplesPanel.Title = 'Assign Replicates to Samples';
            app.G_SampleDataPanel = uigridlayout(app.AssignReplicatestoSamplesPanel);
            app.SampleDataTab = uitabgroup(app.G_SampleDataPanel);
            app.SampleDataTab.SelectionChangedFcn = createCallbackFcn(app, @SampleDataTabSelectionChanged, true);
            app.AllSamplesTab = uitab(app.SampleDataTab);
            app.AllSamplesTab.Title = 'All Samples';
            app.G_AllSamplesTab = uigridlayout(app.AllSamplesTab);
            app.AllListbox = uilistbox(app.G_AllSamplesTab);
            app.AllListbox.Items = {''};
            app.AllListbox.Multiselect = 'on';
            app.AllListbox.Value = {''};
            app.UnassignedSamplesTab = uitab(app.SampleDataTab);
            app.UnassignedSamplesTab.Title = 'Unassigned Samples';
            app.G_UnassignedSamplesTab = uigridlayout(app.UnassignedSamplesTab);
            app.UnassignedListbox = uilistbox(app.G_UnassignedSamplesTab);
            app.UnassignedListbox.Items = {};
            app.UnassignedListbox.Multiselect = 'on';
            app.UnassignedListbox.Value = {};
            app.ReplicateDropDownLabel = uilabel(app.G_EditSampleDataPanel);
            app.ReplicateDropDownLabel.Enable = 'off';
            app.ReplicateDropDownLabel.Text = 'Replicate';
            app.ReplicateDropDown = uidropdown(app.G_EditSampleDataPanel);
            app.ReplicateDropDown.Items = {};
            app.ReplicateDropDown.ValueChangedFcn = createCallbackFcn(app, @ReplicateDropDownValueChanged, true);
            app.ReplicateDropDown.Enable = 'off';
            app.ReplicateDropDown.Value = {};
            app.CreateOSNAPSampleFilesPanel = uipanel(app.G_CreateOSNAPSampleFilesTab);
            app.CreateOSNAPSampleFilesPanel.Title = '2.2. Create O-SNAP Sample Files';
            app.GridLayout7 = uigridlayout(app.CreateOSNAPSampleFilesPanel);
            app.PixelsizenmpixEditFieldLabel = uilabel(app.GridLayout7);
            app.PixelsizenmpixEditFieldLabel.Text = {'Pixel size'; '[nm/pix]'};
            app.PixelsizenmpixEditField = uieditfield(app.GridLayout7, 'numeric');
            app.PixelsizenmpixEditField.Limits = [1 Inf];
            app.PixelsizenmpixEditField.RoundFractionalValues = 'on';
            app.PixelsizenmpixEditField.ValueDisplayFormat = '%.2f';
            app.PixelsizenmpixEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizenmpixEditFieldValueChanged, true);
            app.PixelsizenmpixEditField.Value = 1;
            app.CreateSampleFiles = uibutton(app.GridLayout7, 'push');
            app.CreateSampleFiles.ButtonPushedFcn = createCallbackFcn(app, @CreateSampleFilesPushed, true);
            app.CreateSampleFiles.Enable = 'off';
            app.CreateSampleFiles.Interpreter = 'html';
            app.CreateSampleFiles.Text = {'Create Sample Files'; '<i>Deletes all directory contents</i>'};
            app.AddSampleFiles = uibutton(app.GridLayout7, 'push');
            app.AddSampleFiles.ButtonPushedFcn = createCallbackFcn(app, @AddSampleFilesPushed, true);
            app.AddSampleFiles.Enable = 'off';
            app.AddSampleFiles.Interpreter = 'html';
            app.AddSampleFiles.Text = {'Add Sample Files'; '<i>Overwrites existing data</i>'};
            app.AnalysisParametersTab = uitab(app.TabGroup_main);
            app.AnalysisParametersTab.Title = 'Analysis Parameters';
            app.GridLayout19 = uigridlayout(app.AnalysisParametersTab);
            app.AnalysisOptionsPanel = uipanel(app.GridLayout19);
            app.AnalysisOptionsPanel.Title = '3.1. Analysis Options';
            app.GridLayout10 = uigridlayout(app.AnalysisOptionsPanel);
            app.SampleFeatureGenerationPanel = uipanel(app.GridLayout10);
            app.SampleFeatureGenerationPanel.Title = 'Sample Feature Generation';
            app.GridLayout13 = uigridlayout(app.SampleFeatureGenerationPanel);
            app.ExtractsamplefeaturesCheckBox = uicheckbox(app.GridLayout13);
            app.ExtractsamplefeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtractsamplefeaturesCheckBoxValueChanged, true);
            app.ExtractsamplefeaturesCheckBox.Text = 'Extract sample features';
            app.ExtractsamplefeaturesCheckBox.Value = true;
            app.CreatefeaturetableCheckBox = uicheckbox(app.GridLayout13);
            app.CreatefeaturetableCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreatefeaturetableCheckBoxValueChanged, true);
            app.CreatefeaturetableCheckBox.Text = 'Create feature table';
            app.CreatefeaturetableCheckBox.Value = true;
            app.OtherPlotsPanel = uipanel(app.GridLayout10);
            app.OtherPlotsPanel.Title = 'Other Plots';
            app.GridLayout15 = uigridlayout(app.OtherPlotsPanel);
            app.RunvenndiagramifpossibleCheckBox = uicheckbox(app.GridLayout15);
            app.RunvenndiagramifpossibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunvenndiagramifpossibleCheckBoxValueChanged, true);
            app.RunvenndiagramifpossibleCheckBox.Text = 'Run venn diagram (if possible)';
            app.RunplotradialpersampleCheckBox = uicheckbox(app.GridLayout15);
            app.RunplotradialpersampleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunplotradialpersampleCheckBoxValueChanged, true);
            app.RunplotradialpersampleCheckBox.Text = 'Run plot radial (per sample)';
            app.RunplotradialpersampleCheckBox.Value = true;
            app.RunplotviolinCheckBox = uicheckbox(app.GridLayout15);
            app.RunplotviolinCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunplotviolinCheckBoxValueChanged, true);
            app.RunplotviolinCheckBox.Text = 'Run plot violin';
            app.RunplotviolinCheckBox.Value = true;
            app.FeatureSetEnrichmentAnalysisPanel = uipanel(app.GridLayout10);
            app.FeatureSetEnrichmentAnalysisPanel.Title = 'Feature Set Enrichment Analysis';
            app.GridLayout17 = uigridlayout(app.FeatureSetEnrichmentAnalysisPanel);
            app.RunfeatureenrichmentanalysisCheckBox = uicheckbox(app.GridLayout17);
            app.RunfeatureenrichmentanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunfeatureenrichmentanalysisCheckBoxValueChanged, true);
            app.RunfeatureenrichmentanalysisCheckBox.Text = 'Run feature enrichment analysis';
            app.RunfeatureenrichmentanalysisCheckBox.Value = true;
            app.RankmetricDropDownLabel = uilabel(app.GridLayout17);
            app.RankmetricDropDownLabel.Text = 'Rank metric';
            app.RankmetricDropDown = uidropdown(app.GridLayout17);
            app.RankmetricDropDown.Items = {'S2N', 'ttest', 'ratio', 'diff', 'log2_ratio', 'WAD', 'FCROS', 'MWT'};
            app.RankmetricDropDown.ValueChangedFcn = createCallbackFcn(app, @RankmetricDropDownValueChanged, true);
            app.RankmetricDropDown.Value = 'S2N';
            app.UniversesDropDownLabel = uilabel(app.GridLayout17);
            app.UniversesDropDownLabel.Text = 'Universes';
            app.UniversesDropDown = uidropdown(app.GridLayout17);
            app.UniversesDropDown.Items = {'universe_1'};
            app.UniversesDropDown.Editable = 'on';
            app.UniversesDropDown.ValueChangedFcn = createCallbackFcn(app, @UniversesDropDownValueChanged, true);
            app.UniversesDropDown.Value = 'universe_1';
            app.RunParametersPanel = uipanel(app.GridLayout10);
            app.RunParametersPanel.Title = 'Run Parameters';
            app.GridLayout12 = uigridlayout(app.RunParametersPanel);
            app.SaveCheckBox = uicheckbox(app.GridLayout12);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBoxValueChanged, true);
            app.SaveCheckBox.Text = 'Save';
            app.SaveCheckBox.Value = true;
            app.SaveiferrorCheckBox = uicheckbox(app.GridLayout12);
            app.SaveiferrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveiferrorCheckBoxValueChanged, true);
            app.SaveiferrorCheckBox.Text = 'Save if error';
            app.SaveiferrorCheckBox.Value = true;
            app.CheckoverwriteCheckBox = uicheckbox(app.GridLayout12);
            app.CheckoverwriteCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckoverwriteCheckBoxValueChanged, true);
            app.CheckoverwriteCheckBox.Text = 'Check overwrite';
            app.SuffixEditFieldLabel = uilabel(app.GridLayout12);
            app.SuffixEditFieldLabel.Text = 'Suffix';
            app.SuffixEditField = uieditfield(app.GridLayout12, 'text');
            app.SuffixEditField.ValueChangedFcn = createCallbackFcn(app, @SuffixEditFieldValueChanged, true);
            app.ProcessesSpinnerLabel = uilabel(app.GridLayout12);
            app.ProcessesSpinnerLabel.Text = '# Processes';
            app.ProcessesSpinner = uispinner(app.GridLayout12);
            app.ProcessesSpinner.Limits = [1 12];
            app.ProcessesSpinner.ValueChangedFcn = createCallbackFcn(app, @ProcessesSpinnerValueChanged, true);
            app.ProcessesSpinner.Value = 12;
            app.VolcanoAnalysisPanel = uipanel(app.GridLayout10);
            app.VolcanoAnalysisPanel.Title = 'Volcano Analysis';
            app.GridLayout14 = uigridlayout(app.VolcanoAnalysisPanel);
            app.RunvolcanoanalysisCheckBox = uicheckbox(app.GridLayout14);
            app.RunvolcanoanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunvolcanoanalysisCheckBoxValueChanged, true);
            app.RunvolcanoanalysisCheckBox.Text = 'Run volcano analysis';
            app.RunvolcanoanalysisCheckBox.Value = true;
            app.pvaluecutoffEditField_2Label = uilabel(app.GridLayout14);
            app.pvaluecutoffEditField_2Label.Text = 'p-value cutoff';
            app.pvaluecutoffEditField = uieditfield(app.GridLayout14, 'numeric');
            app.pvaluecutoffEditField.Limits = [0 1];
            app.pvaluecutoffEditField.ValueChangedFcn = createCallbackFcn(app, @pvaluecutoffEditFieldValueChanged, true);
            app.pvaluecutoffEditField.Value = 0.05;
            app.FoldchangecutoffEditField_2Label = uilabel(app.GridLayout14);
            app.FoldchangecutoffEditField_2Label.Text = 'Fold change cutoff';
            app.FoldchangecutoffEditField = uieditfield(app.GridLayout14, 'numeric');
            app.FoldchangecutoffEditField.Limits = [0 Inf];
            app.FoldchangecutoffEditField.ValueChangedFcn = createCallbackFcn(app, @FoldchangecutoffEditFieldValueChanged, true);
            app.FoldchangecutoffEditField.Value = 2;
            app.FeatureSelectionClassificationPanel = uipanel(app.GridLayout10);
            app.FeatureSelectionClassificationPanel.Title = 'Feature Selection & Classification';
            app.GridLayout16 = uigridlayout(app.FeatureSelectionClassificationPanel);
            app.RunPrincipalComponentAnalysisCheckBox = uicheckbox(app.GridLayout16);
            app.RunPrincipalComponentAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunPrincipalComponentAnalysisCheckBoxValueChanged, true);
            app.RunPrincipalComponentAnalysisCheckBox.Text = 'Run Principal Component Analysis';
            app.RunPrincipalComponentAnalysisCheckBox.Value = true;
            app.RunclassificationbatchwiseCheckBox = uicheckbox(app.GridLayout16);
            app.RunclassificationbatchwiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunclassificationbatchwiseCheckBoxValueChanged, true);
            app.RunclassificationbatchwiseCheckBox.Text = 'Run classification batch-wise';
            app.RunclassificationbatchwiseCheckBox.Value = true;
            app.foldsEditFieldLabel = uilabel(app.GridLayout16);
            app.foldsEditFieldLabel.Text = '# folds';
            app.foldsEditField = uieditfield(app.GridLayout16, 'numeric');
            app.foldsEditField.Limits = [0 Inf];
            app.foldsEditField.RoundFractionalValues = 'on';
            app.foldsEditField.ValueChangedFcn = createCallbackFcn(app, @foldsEditFieldValueChanged, true);
            app.foldsEditField.Value = 5;
            app.FeatureselectmaxEditFieldLabel = uilabel(app.GridLayout16);
            app.FeatureselectmaxEditFieldLabel.Text = 'Feature select max';
            app.FeatureselectmaxEditField = uieditfield(app.GridLayout16, 'numeric');
            app.FeatureselectmaxEditField.Limits = [1 144];
            app.FeatureselectmaxEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureselectmaxEditFieldValueChanged, true);
            app.FeatureselectmaxEditField.Value = 12;
            app.MinexplainedEditFieldLabel = uilabel(app.GridLayout16);
            app.MinexplainedEditFieldLabel.Text = 'Min explained (%)';
            app.MinexplainedEditField = uieditfield(app.GridLayout16, 'numeric');
            app.MinexplainedEditField.Limits = [0 1];
            app.MinexplainedEditField.ValueChangedFcn = createCallbackFcn(app, @MinexplainedEditFieldValueChanged, true);
            app.MinexplainedEditField.Value = 0.75;
            app.modelspertypeEditFieldLabel = uilabel(app.GridLayout16);
            app.modelspertypeEditFieldLabel.Text = '# models per type';
            app.modelspertypeEditField = uieditfield(app.GridLayout16, 'numeric');
            app.modelspertypeEditField.Limits = [1 Inf];
            app.modelspertypeEditField.ValueChangedFcn = createCallbackFcn(app, @modelspertypeEditFieldValueChanged, true);
            app.modelspertypeEditField.Value = 5;
            app.ProportionEditFieldLabel = uilabel(app.GridLayout16);
            app.ProportionEditFieldLabel.Text = 'Proportion';
            app.ProportionEditField = uieditfield(app.GridLayout16, 'numeric');
            app.ProportionEditField.Limits = [0 1];
            app.ProportionEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionEditFieldValueChanged, true);
            app.ProportionEditField.Value = 0.2;
            app.RunfeatureselectionCheckBox = uicheckbox(app.GridLayout16);
            app.RunfeatureselectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunfeatureselectionCheckBoxValueChanged, true);
            app.RunfeatureselectionCheckBox.Text = 'Run feature selection';
            app.RunfeatureselectionCheckBox.Value = true;
            app.RunclassificationonwholedataCheckBox = uicheckbox(app.GridLayout16);
            app.RunclassificationonwholedataCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunclassificationonwholedataCheckBoxValueChanged, true);
            app.RunclassificationonwholedataCheckBox.Text = 'Run classification on whole data';
            app.SplitmethodDropDownLabel = uilabel(app.GridLayout16);
            app.SplitmethodDropDownLabel.Text = 'Split method';
            app.SplitmethodDropDown = uidropdown(app.GridLayout16);
            app.SplitmethodDropDown.Items = {'k-fold', 'replicate', 'bootstrap', 'none'};
            app.SplitmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SplitmethodDropDownValueChanged, true);
            app.SplitmethodDropDown.Value = 'k-fold';
            app.RunAnalysisPanel = uipanel(app.GridLayout10);
            app.RunAnalysisPanel.Title = '3.2. Run Analysis';
            app.GridLayout21 = uigridlayout(app.RunAnalysisPanel);
            app.RunStatusLabel = uilabel(app.GridLayout21);
            app.RunStatusLabel.Text = '';
            app.SelectdirectorytosaveanalysisresultsLabel_2 = uilabel(app.GridLayout21);
            app.SelectdirectorytosaveanalysisresultsLabel_2.Text = 'Select directory to save analysis results';
            app.RunOSNAPanalysisButton = uibutton(app.GridLayout21, 'push');
            app.RunOSNAPanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunOSNAPanalysisButtonPushed, true);
            app.RunOSNAPanalysisButton.Enable = 'off';
            app.RunOSNAPanalysisButton.Text = 'Run O-SNAP analysis';
            app.GridLayout23 = uigridlayout(app.G_main);
            app.StatusPanel = uipanel(app.GridLayout23);
            app.StatusPanel.Title = 'Status';
            app.GridLayout22 = uigridlayout(app.StatusPanel);
            app.StatusLabel = uilabel(app.GridLayout22);
            app.StatusLabel.Text = 'Run messages will appear here';
            app.Image = uiimage(app.GridLayout23);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'OSNAP_Logo_cropped.png');
            app.ContextMenu_P = uicontextmenu(app.UIFigure);
            app.EditselectedMenu_P = uimenu(app.ContextMenu_P);
            app.EditselectedMenu_P.MenuSelectedFcn = createCallbackFcn(app, @EditselectedMenu_PSelected, true);
            app.EditselectedMenu_P.Text = 'Edit selected';
            app.DeleteselectedMenu_P = uimenu(app.ContextMenu_P);
            app.DeleteselectedMenu_P.MenuSelectedFcn = createCallbackFcn(app, @DeleteselectedMenu_PSelected, true);
            app.DeleteselectedMenu_P.Text = 'Delete selected';
            app.DeleteallMenuP = uimenu(app.ContextMenu_P);
            app.DeleteallMenuP.MenuSelectedFcn = createCallbackFcn(app, @DeleteallMenuPSelected, true);
            app.DeleteallMenuP.Text = 'Delete all';
            % Assign app.ContextMenu_P
            app.PhenotypesListBox.ContextMenu = app.ContextMenu_P;
            app.ContextMenu_R = uicontextmenu(app.UIFigure);
            app.EditselectedMenu_R = uimenu(app.ContextMenu_R);
            app.EditselectedMenu_R.MenuSelectedFcn = createCallbackFcn(app, @EditselectedMenu_RSelected, true);
            app.EditselectedMenu_R.Text = 'Edit selected';
            app.DeleteselectedMenu_R = uimenu(app.ContextMenu_R);
            app.DeleteselectedMenu_R.MenuSelectedFcn = createCallbackFcn(app, @DeleteselectedMenu_RSelected, true);
            app.DeleteselectedMenu_R.Text = 'Delete selected';
            app.DeleteallMenu_R = uimenu(app.ContextMenu_R);
            app.DeleteallMenu_R.MenuSelectedFcn = createCallbackFcn(app, @DeleteallMenu_RSelected, true);
            app.DeleteallMenu_R.Text = 'Delete all';
            % Assign app.ContextMenu_R
            app.ReplicatesListBox.ContextMenu = app.ContextMenu_R;
            app.ContextMenu_Sample = uicontextmenu(app.UIFigure);
            app.ContextMenu_Sample.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu_SampleOpening, true);
            app.AssignreplicateMenu = uimenu(app.ContextMenu_Sample);
            app.AssignreplicateMenu.Text = 'Assign replicate...';
            app.DeleteselectedMenu = uimenu(app.ContextMenu_Sample);
            app.DeleteselectedMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteselectedMenuSelected, true);
            app.DeleteselectedMenu.Text = 'Delete selected';
            app.DeleteallMenu = uimenu(app.ContextMenu_Sample);
            app.DeleteallMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteallMenuSelected, true);
            app.DeleteallMenu.Text = 'Delete all';
            % Assign app.ContextMenu_Sample
            app.AllListbox.ContextMenu = app.ContextMenu_Sample;
            app.UnassignedListbox.ContextMenu = app.ContextMenu_Sample;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSNAP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end